classdef particleSimulationValidation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlayPauseButton                 matlab.ui.control.StateButton
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Z2MAGauge                       matlab.ui.control.LinearGauge
        Z2MAGaugeLabel                  matlab.ui.control.Label
        Z1MAGauge                       matlab.ui.control.LinearGauge
        Z1MAGaugeLabel                  matlab.ui.control.Label
        Y2MAGauge                       matlab.ui.control.LinearGauge
        Y2MAGaugeLabel                  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X2MAGauge                       matlab.ui.control.LinearGauge
        X2MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        numParticles
        particleFunctions
        simulationTimerProperty
        drawTimerProperty        
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine;
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        fd
        mesh
        haltParticlesInEndZone
        startTime
        currentlyDoingWorkSemaphore
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numParticles = 3; app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf(app.fileID,'%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2));
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            hold (app.UIAxes, 'on');
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            app.fd = FlowData();
            app.fd.FlowValues = app.fd.FlowValues.* 0.001; %Change to centre velocity 1 mm/s. Multiply to alter from here.
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timestep = 0.0001;
            app.timePassed = 0;
            
            if(true) %Write polygon values to file on PC
                app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                        app.X1MAGauge.Value = app.X1MAGauge.Value + 0.05;
                case 'w'
                        app.X1MAGauge.Value = app.X1MAGauge.Value - 0.05;
                case 'e'
                        app.X2MAGauge.Value = app.X2MAGauge.Value + 0.05;
                case 'r'
                        app.X2MAGauge.Value = app.X2MAGauge.Value - 0.05;
                case 'u'
                        app.Y1MAGauge.Value = app.Y1MAGauge.Value + 0.05;
                case 'i'
                        app.Y1MAGauge.Value = app.Y1MAGauge.Value - 0.05;
                case 'o'
                        app.Y2MAGauge.Value = app.Y2MAGauge.Value + 0.05;
                case 'p'
                        app.Y2MAGauge.Value = app.Y2MAGauge.Value - 0.05;
                case 'y'
                    app.X1MAGauge.Value = 0;
                    app.X2MAGauge.Value = 0;
                    app.Y1MAGauge.Value = 0;
                    app.Y1MAGauge.Value = 0;
                    app.Z1MAGauge.Value = 0;
                    app.Z2MAGauge.Value = 0;
                    close all;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.fileID);
            close all;
            delete(app)
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            app.PlayPauseButton.Value = 0;
            app.PlayPauseButtonValueChanged([]);
            value = app.ScenarioDropDown.Value;
            if(value == "Experiment 1")
                app.polygon = app.polygon.change(2);
            else 
                if(value == "Experiment 2")
                    app.polygon = app.polygon.change(3);
                else 
                    if(value == "Experiment 3")
                        app.polygon = app.polygon.change(4);
                    else
                        app.polygon = app.polygon.change(1);
                    end
                end
            end
            delete(app.polyLine);
            delete(app.endLine);
            hold (app.UIAxes, 'on');            
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            %reset particle position & velocity to start area
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
            app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousAcceleration = zeros(length(app.particleArrayLocation), 2);
            
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(value)
                
                %This is where a lot of the looping stuff is changed, including the limiting the timestep to a set time.                
                
                set(app.PlayPauseButton,'BackgroundColor',[0.7,1,0.7]);                
                
                  %Do other things with timing here, must set tMax for example.
                app.timestep = 0.001;
                
                forces = [0.45, 0.5, 0.75, 1, 1.5, 2, 2.25];
                %velocities = [0.000089602, 9.45E-05, 0.00011568, 0.0001336, 0.00016361, 0.00018893, 0.00020036];
                massConstant = pi * 4/3 * 2250;
                %for massIndex = 100:25:500%70:10:170
                      %REMEMBER TO SET PARTICLE DIAMTETER TO INDIVIDUAL
                      %PARTICLE SIZE, NOT AGGREGATION FOR THIS. %OLD DIAMETER:0.0000056
                    %app.ParticleDiametermEditField.Value = nthroot( (massIndex * (8*10^-7)^2 * 0.75) ,3); %Equivalent diameter doesn't match for one
                    %app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
                    newMass = (app.ParticleDiametermEditField.Value/2) ^ 3 * massConstant * 2250;
                    %app.ParticleDiametermEditField.Value = nthroot( (3* massIndex) ,3) * (8*10^-7);
                    %app.ParticleMasskgEditField.Value = newMass;  
                      %This line is commented out when the Mass for loop isn't being used
                    %fprintf(app.fileID,"NO.: " + massIndex + "MASS: " + newMass + " SIZE: " + app.ParticleDiametermEditField.Value + "\r\n");
                      %Comment this out when using Mass  for loop
                    fprintf(app.fileID, "MASS: " + newMass + " SIZE: " + app.ParticleDiametermEditField.Value + "\r\n");
                    
                      %loop over each force
                    for index = 1:length(forces)
                        %app.particleArrayVelocity = [1 0;1 0; 1 0] .* 2.46E-10; %velocities(index);
                        app.startTime = posixtime(datetime(datestr(now)));
                        app.lastUpdate = clock;
                        fprintf(app.fileID, "FORCE:" + forces(index) + "\r\n");
                          %Set force in the x direction
                        app.X1MAGauge.Value = forces(index);
                        loopIndex = 1;
                        
                        while(~any(app.haltParticlesInEndZone) & (app.timePassed < 10))
                            app.lastUpdate(6) = app.lastUpdate(6) - 0.0001; 
                            timerCallback(app);
                            if(mod(loopIndex,10) == 0)      %Higher modulo number for faster                      
                                drawTimerCallback(app);
                                pause(0.1);
                            end
                            loopIndex = loopIndex + 1;
                        end
                        app.timePassed = 0;
                        app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
                        app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
                        app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
                        app.particleArrayPreviousLocation = app.particleArrayLocation;
                        app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
                        app.particleArrayBaseVelocityStartTime = clock;
                        app.particleArrayPreviousDragForce = zeros(length(app.particleArrayLocation), 2);
                        app.particleArrayPreviousAcceleration = zeros(length(app.particleArrayLocation), 2);
                        app.haltParticlesInEndZone = 0 .* app.haltParticlesInEndZone;
                    end
                %end
                %Now close the app
                fclose(app.fileID);
                close all;
                delete(app)
                disp("App Stopped")
            else
                set(app.PlayPauseButton,'BackgroundColor',[1,0.7,0.7]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Enable = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Enable = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MajorTicks = [-2.5 0 2.5];
            app.X1MAGauge.MajorTickLabels = {'-2.5', '0', '2.5'};
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.X2MAGaugeLabel = uilabel(app.LeftPanel);
            app.X2MAGaugeLabel.Enable = 'off';
            app.X2MAGaugeLabel.Visible = 'off';
            app.X2MAGaugeLabel.Text = 'X2 (MA)';
            app.X2MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X2MAGauge.Limits = [-1 1];
            app.X2MAGauge.Enable = 'off';
            app.X2MAGauge.Visible = 'off';
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MajorTicks = [-2.5 0 2.5];
            app.Y1MAGauge.MajorTickLabels = {'-2.5', '0', '2.5'};
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y2MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y2MAGaugeLabel.Enable = 'off';
            app.Y2MAGaugeLabel.Visible = 'off';
            app.Y2MAGaugeLabel.Text = 'Y2 (MA)';
            app.Y2MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y2MAGauge.Limits = [-1 1];
            app.Y2MAGauge.Enable = 'off';
            app.Y2MAGauge.Visible = 'off';
            app.Z1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Z1MAGaugeLabel.Enable = 'off';
            app.Z1MAGaugeLabel.Visible = 'off';
            app.Z1MAGaugeLabel.Text = {'Z1 (MA'; ')'};
            app.Z1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Z1MAGauge.Limits = [-1 1];
            app.Z1MAGauge.Enable = 'off';
            app.Z1MAGauge.Visible = 'off';
            app.Z2MAGaugeLabel = uilabel(app.LeftPanel);
            app.Z2MAGaugeLabel.Enable = 'off';
            app.Z2MAGaugeLabel.Visible = 'off';
            app.Z2MAGaugeLabel.Text = 'Z2 (MA)';
            app.Z2MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Z2MAGauge.Limits = [-1 1];
            app.Z2MAGauge.Enable = 'off';
            app.Z2MAGauge.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Enable = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Enable = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Enable = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Enable = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Enable = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training', 'Experiment 1', 'Experiment 2', 'Experiment 3'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training';
            app.PlayPauseButton = uibutton(app.LeftPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Tooltip = {'Play Pause Button'; 'Use to halt and restart the simulator'};
            app.PlayPauseButton.Text = 'Play / Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.00096 0.00096];
            app.UIAxes.YLim = [-0.00096 0.00096];
            app.UIAxes.ZLim = [-0.00096 0.00096];
            app.UIAxes.Tag = 'particleWorskace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationValidation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end